classdef GUI_experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        ElevationPanel       matlab.ui.container.Panel
        AzimuthPanel         matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        GridLayout7          matlab.ui.container.GridLayout
        LoadButton           matlab.ui.control.Button
        Export               matlab.ui.control.Button
        AudioFile            matlab.ui.control.DropDown
        AudioLabel_2         matlab.ui.control.Label
        GridLayout6          matlab.ui.container.GridLayout
        ReferenceSwitch      matlab.ui.control.Switch
        SourceLabel          matlab.ui.control.Label
        FPositions           matlab.ui.control.DropDown
        FixedlocationsLabel  matlab.ui.control.Label
        GridLayout8          matlab.ui.container.GridLayout
        Loop                 matlab.ui.control.Switch
        AudioLabel           matlab.ui.control.Label
        PlayStop             matlab.ui.control.Button
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout9          matlab.ui.container.GridLayout
        UseButton            matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        UserangeButton       matlab.ui.control.Button
        MemSlider            matlab.ui.control.RangeSlider
        SSliderLabel         matlab.ui.control.Label
        MemListBox           matlab.ui.control.ListBox
        MListBoxLabel        matlab.ui.control.Label
        Sl3                  matlab.ui.control.Label
        Sl2                  matlab.ui.control.Label
        Sl1                  matlab.ui.control.Label
        ParametersLabel      matlab.ui.control.Label
        Slider3              matlab.ui.control.Slider
        Label_3              matlab.ui.control.Label
        Slider2              matlab.ui.control.Slider
        Label_2              matlab.ui.control.Label
        Slider1              matlab.ui.control.Slider
        Label                matlab.ui.control.Label
    end

    
    properties (Access = private)
        pax
        ax
        pics
        alpha
        decoder
        itd_decoder
        itds
        h_delay
        latent
        sliders_v
        hrirs
        filters
        audio
        pos_sph
        pos
        id_pos
        is_playing
        az
        el
        pca_info
        freq
        para
        parapool
        buffer_size
        fixed_positions
        memory_slots
        ranged
        % sl_order

        audio_size
        audio_idx
        audio_samples
        audio_ended
    end
    
    methods (Access = private)        

        function Update_filter(app)            
            tmp=sphToRect([app.az app.el 1]);
            app.id_pos=dsearchn(app.pos,tmp);
            app.filters=fft([squeeze(app.hrirs(app.id_pos,:,:));zeros(512-64,2)]);            
            app.Update_plots();
        end
        
        function Update_data(app)
            % app.latent=[];
            % for i=1:5
            %     app.latent=[app.latent,app.(['Slider' num2str(i)]).Value];               
            % end            
            app.latent=(app.sliders_v.*app.pca_info.max_coes) * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=((app.sliders_v.*app.pca_info.sd)./app.pca_info.mean) * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=(app.latent.*app.pca_info.max_coes')' * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=((app.latent-app.pca_info.mean)/app.pca_info.sd)' * app.pca_info.coeff'+app.pca_info.mu;
            app.hrirs=reshape(predict(app.decoder,app.latent),2,64,397);
            app.hrirs=permute(app.hrirs,[3,2,1]);
            app.itds=predict(app.itd_decoder,app.latent);
            app.Update_filter();
        end

        function Update_plots(app)
            for l=1:2
                cla(app.pax{l},'reset');
                if l==1
                    polarscatter(app.pax{l},deg2rad(app.pos_sph(app.id_pos,1)),cos(deg2rad(app.pos_sph(app.id_pos,2))),300,'filled');
                    set(app.pax{l},'ThetaZeroLocation','top');
                    % title(app.pax{l},'Azimuth');
                else
                    tmp=app.pos_sph(app.id_pos,l);
                    if app.pos_sph(app.id_pos,1)>90 && app.pos_sph(app.id_pos,1)<270, tmp=tmp+180; end
                    polarscatter(app.pax{l},deg2rad(tmp),1,300,'filled');
                    set(app.pax{l},'ThetaZeroLocation','left');
                    app.pax{l}.ThetaTickLabel = {'0', '30', '60', '90', '60', '30', '0', '-30', '-60', '-90', '-60', '-30', '0'};
                    % title(app.pax{l},'Elevation');
                end
                app.pax{l}.RTickLabel = {};
                rlim(app.pax{l},[0 1.1]);
                set(app.pax{l}, 'color', 'none');
                
                set(app.pax{l},'ThetaDir','clockwise');
                set(app.pax{l},'ThetaGrid','on');
                set(app.pax{l},'RGrid','on');
                app.pax{l}.FontSize=18;
            end            
        end

        function audio_frame=Next_block(app)                        
            if app.audio_idx>=app.audio_size
                app.audio_ended=true;
                app.audio_idx=app.audio_idx-app.audio_size;
            end
            audio_frame=app.audio_samples(app.audio_idx:app.audio_idx+(app.buffer_size-1));
            app.audio_idx=app.audio_idx+app.buffer_size;
        end

        function Player(app)       
            % disp(size(app.itds));
            app.audio_ended=false; app.audio_idx=1;
            app.is_playing=true;
            app.PlayStop.Text='Stop (P)';
            deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','Default');
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','Rubix22');
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','ZoomAudioDevice');
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','MacBook Pro Speakers');
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','External Headphones');
            buffer=zeros(4096,2);
            buffer_in=zeros(128,1);
            han=hann(256,'periodic');            
            while app.is_playing
                    audioIn = app.Next_block();

                    buffer_in=[buffer_in;audioIn];
                    for j=[(0:128:app.buffer_size-1)+1]
                        tmp=fft([buffer_in(j:j+255).*han;zeros(256,1)]);
                        l_d=0; r_d=0;
                        if app.itds(app.id_pos)<0, l_d=round(256*abs(app.itds(app.id_pos))); else, r_d=round(256*abs(app.itds(app.id_pos))); end
                        % buffer(j:j+511,1)=buffer(j:j+511,1)+real(ifft(tmp)); buffer(j:j+511,2)=buffer(j:j+511,2)+real(ifft(tmp));
                        buffer(j+l_d:l_d+j+511,1)=buffer(j+l_d:l_d+j+511,1)+real(ifft(app.filters(:,1).*tmp)); 
                        buffer(j+r_d:r_d+j+511,2)=buffer(j+r_d:r_d+j+511,2)+real(ifft(app.filters(:,2).*tmp));
                        % buffer(j:j+511,:)=buffer(j:j+511,:)+real(ifft([app.filter(:,1).*tmp,app.filter(:,2).*tmp]));
                    end                
                    deviceWriter(fliplr(buffer(1:app.buffer_size,:)));
                    % pause(0);
                    drawnow limitrate
                    buffer=[buffer(app.buffer_size+1:end,:);zeros(app.buffer_size,2)];
                    buffer_in=buffer_in(app.buffer_size+1:end);
                    if app.audio_ended && ~strcmp(app.Loop.Value,'Loop'), break; end                    
            end
            app.PlayStop.Text='Play (P)';
            app.is_playing=false;
            release(deviceWriter);
            
        end

    end
    methods (Access = public,Static)        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.parapool=gcp;
            % app.pax{1}=polaraxes; app.pax{2}=polaraxes;
            % app.ax{1}=axes(app.GridLayout3); app.ax{2}=axes(app.GridLayout3);
            % app.pax{1}=polaraxes(app.ax{1}); app.pax{2}=polaraxes(app.ax{2});
            % app.pax{1}=polaraxes('Position',app.ax{1}.Position); app.pax{2}=polaraxes('Position',app.ax{2}.Position);
            % linkaxes([app.ax{1} app.pax{1}]); linkaxes([app.ax{2} app.pax{2}]);
            % app.pax{1}=polaraxes(app.GridLayout3); app.pax{2}=polaraxes(app.GridLayout3);
            % app.ax{1}=axes(app.pax{1}); app.ax{2}=axes(app.pax{1});
            % app.ax{1}=axes('Position',app.pax{1}.Position); app.ax{2}=axes('Position',app.pax{2}.Position);
            app.ax{1}=axes(app.AzimuthPanel); app.pax{1}=polaraxes(app.AzimuthPanel);
            app.ax{2}=axes(app.ElevationPanel); app.pax{2}=polaraxes(app.ElevationPanel);
            
            [app.pics{1},~,app.alpha{1}]=imread('./Pictures/top.png'); [app.pics{2},~,app.alpha{2}]=imread('./Pictures/perfil.png'); 
            image(app.ax{1},app.pics{1},'AlphaData',app.alpha{1}); image(app.ax{2},app.pics{2},'AlphaData',app.alpha{2});
            app.ax{1}.Color='none'; app.ax{2}.Color='none';
            xlim(app.ax{1},[-550 1500]); ylim(app.ax{1},[-500 1500]);
            xlim(app.ax{2},[-500 1000]); ylim(app.ax{2},[-500 1000]);
            axis(app.ax{1},'off'); axis(app.ax{2},'off');

            app.decoder = importNetworkFromTensorFlow('./Models/decoder');
            app.itd_decoder= importNetworkFromTensorFlow('./Models/itd_decoder');
            load('pca_info.mat'); app.pca_info=pca_info;
            load('pos.mat');
            app.buffer_size=2048;
            [app.audio_samples,Fs]=audioread('./Stimulus/Guitar.wav');                    
            app.audio_size=size(app.audio_samples,1);
            app.audio_samples=[app.audio_samples;app.audio_samples(1:app.buffer_size)];
            app.pos_sph=pos;
            app.pos=sphToRect([pos,repmat(1,[397 1])]);
            app.az=0;app.el=-30;
            app.freq= (0 : 512/2) * 35200 / 512;
            app.is_playing=false;            
            app.sliders_v=zeros(1,5);
            app.Update_data();
            % app.fixed_positions=[0,-30;0,0;0,30;0,60;0,90;45,0;90,0;180,-30;180,0;180,30];
            app.fixed_positions=[0,-30;0,90;180,0];

            app.memory_slots=zeros(9,5);
            app.ranged=false;
            % app.sl_order=randperm(3);
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(1)=changingValue;
            % app.Sl1.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(2)=changingValue;
            % app.Sl2.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(3)=changingValue;
            % app.Sl3.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Button pushed function: PlayStop
        function PlayStopButtonPushed(app, event)
            if ~app.is_playing
                % app.para=parfeval(backgroundPool,@player);                
                % app.para=parfeval(app.parapool,@app.Player,0);
                app.Player;
            else
                app.is_playing=false;
            end
        end

        % Value changed function: AudioFile
        function AudioFileValueChanged(app, event)
            value = app.AudioFile.ValueIndex;
            app.is_playing=false;
            switch value
                case 1
                    [app.audio_samples,Fs]=audioread('./Stimulus/Guitar.wav'); 
                case 2
                    [app.audio_samples,Fs]=audioread('./Stimulus/hvd_002.wav');
                    app.audio_samples=[app.audio_samples;zeros(8096,1)];
                case 3
                    [app.audio_samples,Fs]=audioread('./Stimulus/TrainNoise.wav');
                    app.audio_samples=[app.audio_samples;zeros(8096,1)]; %704
                case 4
                    [filename,pathname] = uigetfile('*.wav');
                    pathname=[pathname filename];
                    [app.audio_samples,Fs]=audioread(pathname); app.audio_samples=app.audio_samples(:,1);
                    if Fs~=35200, app.audio_samples=resample(app.audio_samples,35200,Fs); end
            end
            app.audio_size=size(app.audio_samples,1);
            app.audio_samples=[app.audio_samples;app.audio_samples(1:app.buffer_size*2)];
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if isstrprop(key, 'digit')
                % [~,value]=ismember(key,['1','2','3','4','5','6','7','8','9','0']);
                % app.el=app.fixed_positions(value,2);
                % app.az=app.fixed_positions(value,1);
                % app.Update_filter();
            else
                switch key
                    case 'p'
                        if ~app.is_playing
                            app.Player();
                        else
                            app.is_playing=false;  
                        end
                    % case 'r'
                    %     tmp=randi([1 397]);
                    %     app.el=app.pos_sph(tmp,2);
                    %     app.az=app.pos_sph(tmp,1);
                    %     app.Update_filter();
                    case 'r'
                        value = app.ReferenceSwitch.Value;
                        if strcmp(value,'Reference')
                            app.az=0; app.el=0;
                            app.FPositions.Enable="off";
                            for i=1:3, app.(['Slider' num2str(i)]).Enable="off"; end
                            app.ReferenceSwitch.Value='Selected source';
                        else
                            for i=1:3, app.(['Slider' num2str(i)]).Enable="on"; end
                            app.FPositions.Enable="on";
                            value = app.FPositions.ValueIndex;
                            app.el=app.fixed_positions(value,2);
                            app.az=app.fixed_positions(value,1);
                            app.ReferenceSwitch.Value='Reference';
                        end
                        app.Update_filter();
                end
            end            
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            value = app.Slider1.Value;
            app.sliders_v(1)=value;
            % app.sliders_v(app.sl_order(1))=value;
            app.Sl1.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.sliders_v(2)=value;
            % app.sliders_v(app.sl_order(2))=value;
            app.Sl2.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            app.sliders_v(3)=value;
            % app.sliders_v(app.sl_order(3))=value;
            app.Sl3.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: FPositions
        function FPositionsValueChanged(app, event)
            value = app.FPositions.ValueIndex;
            app.el=app.fixed_positions(value,2);
            app.az=app.fixed_positions(value,1);
            app.Update_filter();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.memory_slots(app.MemListBox.ValueIndex,:)=app.sliders_v;
            tmp=app.MemListBox.Items; tmp{app.MemListBox.ValueIndex}=convertStringsToChars(strcat("memory ",int2str(app.MemListBox.ValueIndex)," (saved)"));
            set(app.MemListBox,'Items',tmp);
            for i=1:3, app.(['Slider' num2str(i)]).Value=0; app.(['Sl' num2str(i)]).Text=int2str(0);   end
            app.sliders_v=zeros(1,5);
            % app.sl_order=randperm(3);
            app.Update_data();

        end

        % Button pushed function: UserangeButton
        function UserangeButtonPushed(app, event)
            if app.ranged
                for i=1:3, app.(['Slider' num2str(i)]).Limits=[-1 1];  end
                app.UserangeButton.Text="Use";
            else
                tmp_min=min(app.memory_slots(app.MemSlider.Value(1):app.MemSlider.Value(2),:));
                tmp_max=max(app.memory_slots(app.MemSlider.Value(1):app.MemSlider.Value(2),:));
                for i=1:3
                    if tmp_min(i)==tmp_max(i)
                        if tmp_min(i)==0
                            continue;
                        else
                            tmp_min(i)=-abs(tmp_min(i)); tmp_max(i)=abs(tmp_max(i));                            
                        end                        
                    end
                    app.(['Slider' num2str(i)]).Limits=[tmp_min(i) tmp_max(i)]; 
                end
                app.UserangeButton.Text="Reset";
            end
            app.ranged=~app.ranged;
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            % say -v Kyoko "[[slnc 200]] かほ　しらい [[slnc 200]]" --data-format=LEF32@35200 -o voice.wav
            [file, path] = uiputfile({'*.*'});
            mkdir([path file]);
            data.pca_info=app.pca_info;            
            
            data.sliders=app.sliders_v;
            % tmp_sliders=app.sliders_v;
            % data.sliders=mean(app.memory_slots(1:3,:));
            % app.sliders_v=data.sliders;
            app.Update_data();
            data.latent=app.latent;

            data.memory=app.memory_slots;
            save([path file '/data.mat'],'data');       
            % stimulus={'./Stimulus/voice.wav','./Stimulus/Guitar2.wav'}; stimulus_id={'S','G'};
            stimulus={'./Stimulus/Guitar3.wav'}; stimulus_id={'G'};
            han=hann(256,'periodic');
            w = waitbar(0,'Please wait...');
            for i=1:length(stimulus_id)
                waitbar(i/(length(stimulus_id)+3),w,'Please wait...');        
                [samples,Fs]=audioread(stimulus{i}); samples=samples(:,1);
                if Fs~=35200, samples=resample(samples,35200,Fs); end
                if mod(length(samples),256)>0, samples=[samples;zeros(mod(length(samples),256),1)]; end
                samples=[samples;zeros(256,1)];
                for j=[zeros(5,1) [-30:30:90]';ones(4,1).*180 [60:-30:-30]']'
                    if ismember(j',[0 -30; 0 0; 0 90; 180 0],"rows"), continue; end
                    buffer=zeros(length(samples)+512,2);
                    [~,id_p]=ismember(j',app.pos_sph,'rows');
                    filtros=fft([squeeze(app.hrirs(id_p,:,:));zeros(512-64,2)]); 
                    l_d=0; r_d=0;
                    if app.itds(id_p)<0, l_d=round(256*abs(app.itds(id_p))); else, r_d=round(256*abs(app.itds(id_p))); end
                    for k=[(0:128:length(samples)-256)+1]
                        tmp=fft([samples(k:k+255).*han;zeros(256,1)]);
                        % buffer(j:j+511,1)=buffer(j:j+511,1)+real(ifft(tmp)); buffer(j:j+511,2)=buffer(j:j+511,2)+real(ifft(tmp));
                        buffer(k+l_d:l_d+k+511,1)=buffer(k+l_d:l_d+k+511,1)+real(ifft(filtros(:,1).*tmp)); 
                        buffer(k+r_d:r_d+k+511,2)=buffer(k+r_d:r_d+k+511,2)+real(ifft(filtros(:,2).*tmp));
                        % buffer(j:j+511,:)=buffer(j:j+511,:)+real(ifft([app.filter(:,1).*tmp,app.filter(:,2).*tmp]));
                    end
                    for l=1:3
                        audiowrite([path file '/P_' stimulus_id{i} '_' int2str(j(1)) '_' int2str(j(2)) '_' int2str(l) '.wav'],fliplr(buffer),35200);
                    end      
                end
            end
            stimulus={'./Stimulus/Guitar.wav','./Stimulus/Guitar.wav','./Stimulus/Guitar.wav'};
            stimulus_id={'G','G','G'}; tmp_pos=[0 -30;0 90;180 0];
            for i=1:length(stimulus_id)
                waitbar((i+length(stimulus_id))/(length(stimulus_id)+3),w,'Please wait...');
                [samples,Fs]=audioread(stimulus{i}); samples=samples(:,1);
                if Fs~=35200, samples=resample(samples,35200,Fs); end
                if mod(length(samples),256)>0, samples=[samples;zeros(mod(length(samples),256),1)]; end
                samples=[samples;zeros(256,1)];
                j=tmp_pos(i,:)';
                buffer=zeros(length(samples)+512,2);
                [~,id_p]=ismember(j',app.pos_sph,'rows');
                filtros=fft([squeeze(app.hrirs(id_p,:,:));zeros(512-64,2)]); 
                l_d=0; r_d=0;
                if app.itds(id_p)<0, l_d=round(256*abs(app.itds(id_p))); else, r_d=round(256*abs(app.itds(id_p))); end
                for k=[(0:128:length(samples)-256)+1]
                    tmp=fft([samples(k:k+255).*han;zeros(256,1)]);
                    % buffer(j:j+511,1)=buffer(j:j+511,1)+real(ifft(tmp)); buffer(j:j+511,2)=buffer(j:j+511,2)+real(ifft(tmp));
                    buffer(k+l_d:l_d+k+511,1)=buffer(k+l_d:l_d+k+511,1)+real(ifft(filtros(:,1).*tmp)); 
                    buffer(k+r_d:r_d+k+511,2)=buffer(k+r_d:r_d+k+511,2)+real(ifft(filtros(:,2).*tmp));
                    % buffer(j:j+511,:)=buffer(j:j+511,:)+real(ifft([app.filter(:,1).*tmp,app.filter(:,2).*tmp]));
                end
                audiowrite([path file '/' int2str(i) 'T_' stimulus_id{i} '_' int2str(j(1)) '_' int2str(j(2)) '.wav'],fliplr(buffer),35200);
            end
            close(w);

            % app.sliders_v=tmp_sliders;
            % app.Update_data();
        end

        % Value changed function: ReferenceSwitch
        function ReferenceSwitchValueChanged(app, event)
            value = app.ReferenceSwitch.Value;
            if strcmp(value,'Reference')
                app.az=0; app.el=0;
                app.FPositions.Enable="off";
                for i=1:3, app.(['Slider' num2str(i)]).Enable="off"; end
            else
                for i=1:3, app.(['Slider' num2str(i)]).Enable="on"; end
                app.FPositions.Enable="on";
                value = app.FPositions.ValueIndex;
                app.el=app.fixed_positions(value,2);
                app.az=app.fixed_positions(value,1);
            end
            app.Update_filter();
        end

        % Button pushed function: UseButton
        function UseButtonPushed(app, event)
            app.sliders_v(1:3)=app.memory_slots(app.MemListBox.ValueIndex,1:3);
            for i=1:3, app.(['Slider' num2str(i)]).Value=app.sliders_v(i); app.(['Sl' num2str(i)]).Text=num2str(app.sliders_v(i));   end
            % for i=1:3, app.(['Slider' num2str(i)]).Value=app.sliders_v(app.sl_order(i)); app.(['Sl' num2str(i)]).Text=num2str(app.sliders_v(app.sl_order(i)));   end
            app.Update_data();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            load([pathname filename]);
            app.pca_info=data.pca_info;
            app.sliders_v=data.sliders;
            app.Update_data();
            for i=1:3, app.(['Slider' num2str(i)]).Value=app.sliders_v(i); app.(['Sl' num2str(i)]).Text=num2str(app.sliders_v(i));   end
            app.memory_slots=data.memory;
            % for i=1:9,
            % tmp=app.MemListBox.Items; tmp{app.MemListBox.ValueIndex}=convertStringsToChars(strcat("memory ",int2str(app.MemListBox.ValueIndex)," (saved)"));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '1';
            app.Slider1 = uislider(app.GridLayout2);
            app.Slider1.Limits = [-1 1];
            app.Slider1.MajorTicks = [];
            app.Slider1.MajorTickLabels = {''};
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider1.MinorTicks = [];
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '2';
            app.Slider2 = uislider(app.GridLayout2);
            app.Slider2.Limits = [-1 1];
            app.Slider2.MajorTicks = [];
            app.Slider2.MajorTickLabels = {''};
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.MinorTicks = [];
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '3';
            app.Slider3 = uislider(app.GridLayout2);
            app.Slider3.Limits = [-1 1];
            app.Slider3.MajorTicks = [];
            app.Slider3.MajorTickLabels = {''};
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.Slider3.MinorTicks = [];
            app.ParametersLabel = uilabel(app.GridLayout2);
            app.ParametersLabel.Text = 'Parameters';
            app.Sl1 = uilabel(app.GridLayout2);
            app.Sl1.Visible = 'off';
            app.Sl1.Text = '0';
            app.Sl2 = uilabel(app.GridLayout2);
            app.Sl2.Visible = 'off';
            app.Sl2.Text = '0';
            app.Sl3 = uilabel(app.GridLayout2);
            app.Sl3.Visible = 'off';
            app.Sl3.Text = '0';
            app.MListBoxLabel = uilabel(app.GridLayout2);
            app.MListBoxLabel.Text = 'M';
            app.MemListBox = uilistbox(app.GridLayout2);
            app.MemListBox.Items = {'memory 1', 'memory 2', 'memory 3', 'memory 4', 'memory 5', 'memory 6', 'memory 7', 'memory 8', 'memory 9'};
            app.MemListBox.Value = 'memory 1';
            app.SSliderLabel = uilabel(app.GridLayout2);
            app.SSliderLabel.Text = 'S';
            app.MemSlider = uislider(app.GridLayout2, 'range');
            app.MemSlider.Limits = [1 9];
            app.MemSlider.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.MemSlider.MinorTicks = [1 2 3 4 5 6 7 8 9];
            app.MemSlider.Enable = 'off';
            app.MemSlider.Visible = 'off';
            app.MemSlider.Value = [1 3];
            app.UserangeButton = uibutton(app.GridLayout2, 'push');
            app.UserangeButton.ButtonPushedFcn = createCallbackFcn(app, @UserangeButtonPushed, true);
            app.UserangeButton.Text = {'Use '; 'range'};
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.SaveButton = uibutton(app.GridLayout9, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UseButton = uibutton(app.GridLayout9, 'push');
            app.UseButton.ButtonPushedFcn = createCallbackFcn(app, @UseButtonPushed, true);
            app.UseButton.Text = 'Use';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PlayStop = uibutton(app.GridLayout6, 'push');
            app.PlayStop.ButtonPushedFcn = createCallbackFcn(app, @PlayStopButtonPushed, true);
            app.PlayStop.Text = 'Play (P)';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.AudioLabel = uilabel(app.GridLayout8);
            app.AudioLabel.Text = 'Audio (P):';
            app.Loop = uiswitch(app.GridLayout8, 'slider');
            app.Loop.Items = {'No loop', 'Loop'};
            app.Loop.Value = 'Loop';
            app.FixedlocationsLabel = uilabel(app.GridLayout6);
            app.FixedlocationsLabel.Text = 'Fixed location';
            app.FPositions = uidropdown(app.GridLayout6);
            app.FPositions.Items = {'[0,-30]', '[0,90]', '[180,0]'};
            app.FPositions.ValueChangedFcn = createCallbackFcn(app, @FPositionsValueChanged, true);
            app.FPositions.Value = '[0,-30]';
            app.SourceLabel = uilabel(app.GridLayout6);
            app.SourceLabel.Text = 'Location (R)';
            app.ReferenceSwitch = uiswitch(app.GridLayout6, 'slider');
            app.ReferenceSwitch.Items = {'Selected location', 'Reference'};
            app.ReferenceSwitch.ValueChangedFcn = createCallbackFcn(app, @ReferenceSwitchValueChanged, true);
            app.ReferenceSwitch.Value = 'Selected location';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.AudioLabel_2 = uilabel(app.GridLayout7);
            app.AudioLabel_2.Text = 'Audio';
            app.AudioFile = uidropdown(app.GridLayout7);
            app.AudioFile.Items = {'Guitar', 'Voice', 'Noise', 'Custom'};
            app.AudioFile.ValueChangedFcn = createCallbackFcn(app, @AudioFileValueChanged, true);
            app.AudioFile.Value = 'Guitar';
            app.Export = uibutton(app.GridLayout7, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
            app.LoadButton = uibutton(app.GridLayout7, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AzimuthPanel = uipanel(app.GridLayout3);
            app.AzimuthPanel.Title = 'Azimuth';
            app.ElevationPanel = uipanel(app.GridLayout3);
            app.ElevationPanel.Title = 'Elevation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end