classdef GUI_perso < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        Export                       matlab.ui.control.Button
        Random                       matlab.ui.control.Button
        AudioFile                    matlab.ui.control.DropDown
        AudiofileDropDownLabel       matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        FPositions                   matlab.ui.control.DropDown
        FixedpositionsDropDownLabel  matlab.ui.control.Label
        GridLayout8                  matlab.ui.container.GridLayout
        Loop                         matlab.ui.control.Switch
        AudioLabel                   matlab.ui.control.Label
        PlayStop                     matlab.ui.control.Button
        ElLabel                      matlab.ui.control.Label
        AzLabel                      matlab.ui.control.Label
        ElKnob                       matlab.ui.control.Knob
        ElevationElKnobLabel         matlab.ui.control.Label
        AzKnob                       matlab.ui.control.Knob
        AzimuthAzKnobLabel           matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        TypePlot                     matlab.ui.control.DropDown
        TypeofplotDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        GridLayout2                  matlab.ui.container.GridLayout
        UserangeButton               matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        MemSlider                    matlab.ui.control.RangeSlider
        SSliderLabel                 matlab.ui.control.Label
        MemListBox                   matlab.ui.control.ListBox
        MListBoxLabel                matlab.ui.control.Label
        Sl5                          matlab.ui.control.Label
        Sl4                          matlab.ui.control.Label
        Sl3                          matlab.ui.control.Label
        Sl2                          matlab.ui.control.Label
        Sl1                          matlab.ui.control.Label
        ParametersLabel              matlab.ui.control.Label
        Slider5                      matlab.ui.control.Slider
        Slider_4Label                matlab.ui.control.Label
        Slider4                      matlab.ui.control.Slider
        Slider_3Label                matlab.ui.control.Label
        Slider3                      matlab.ui.control.Slider
        Slider_2Label                matlab.ui.control.Label
        Slider2                      matlab.ui.control.Slider
        Label_2                      matlab.ui.control.Label
        Slider1                      matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        decoder
        itd_decoder
        itds
        h_delay
        latent
        sliders_v
        hrirs
        filters
        audio
        pos_sph
        pos
        id_pos
        is_playing
        az
        el
        pca_info
        freq
        para
        parapool
        buffer_size
        fixed_positions
        memory_slots
        ranged

        audio_size
        audio_idx
        audio_samples
        audio_ended
    end
    
    methods (Access = private)        

        function Update_filter(app)            
            tmp=sphToRect([app.az app.el 1]);
            app.id_pos=dsearchn(app.pos,tmp);
            app.filters=fft([squeeze(app.hrirs(app.id_pos,:,:));zeros(512-64,2)]);            
            app.Update_plots();
        end
        
        function Update_data(app)
            % app.latent=[];
            % for i=1:5
            %     app.latent=[app.latent,app.(['Slider' num2str(i)]).Value];               
            % end            
            app.latent=(app.sliders_v.*app.pca_info.max_coes) * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=((app.sliders_v.*app.pca_info.sd)./app.pca_info.mean) * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=(app.latent.*app.pca_info.max_coes')' * app.pca_info.coeff'+app.pca_info.mu;
            % app.latent=((app.latent-app.pca_info.mean)/app.pca_info.sd)' * app.pca_info.coeff'+app.pca_info.mu;
            app.hrirs=reshape(predict(app.decoder,app.latent),2,64,397);
            app.hrirs=permute(app.hrirs,[3,2,1]);
            app.itds=predict(app.itd_decoder,app.latent);
            app.Update_filter();
        end

        function Update_plots(app)
            cla(app.UIAxes,'reset');
            % reset(app.UIAxes);
            switch app.TypePlot.ValueIndex
                case 1
                    % plot(app.UIAxes,squeeze(app.hrirs(id_pos,:,:))+[zeros(64,1),ones(64,1)],'LineWidth',2);
                    plot(app.UIAxes,fliplr(squeeze(app.hrirs(app.id_pos,:,:))),'LineWidth',2);
                    app.UIAxes.YLim=[-1 1];
                    app.UIAxes.YTick=-1:0.5:1;
                    app.UIAxes.YGrid='on';          
                    xlabel(app.UIAxes,"Samples");
                    ylabel(app.UIAxes,"Amplitud");
                    % app.UIAxes.YTickLabels={''};
                case 2
                    % app.UIAxes.YLim=[-95 10];
                    semilogx(app.UIAxes,app.freq,fliplr([20*log10(abs(app.filters(1:(512/2 + 1),1))),20*log10(abs(app.filters(1:(512/2 + 1),2)))]),'LineWidth',2);
                    app.UIAxes.XTick=[100 1000 4000 8000 16000];
                    app.UIAxes.XTickLabels={'100','1k','4k','8k','16k'};
                    app.UIAxes.YTick=-90:10:10;
                    % app.UIAxes.YTick=[-90 -60 -30 0];
                    % app.UIAxes.YTickLabels={'-90dB','-60dB','-30dB','0dB'};
                    app.UIAxes.XGrid='on';
                    app.UIAxes.YGrid='on';
                    xlabel(app.UIAxes,"Hz");
                    ylabel(app.UIAxes,"dB");
                    app.UIAxes.YLim=[-40 10];
                    app.UIAxes.XLim=[100 16000];                    
                case 3                    
                    hold(app.UIAxes,'on');
                    for i=-30:10:80
                        plot(app.UIAxes,app.pos_sph(app.pos_sph(:,2)==i,1),(256*app.itds(app.pos_sph(:,2)==i))/35200,'LineWidth',2);
                    end
                    % app.UIAxes.YLim=[-1 1];
                    % app.UIAxes.YTick=[0.5];
                    app.UIAxes.YGrid='on';
                    % app.UIAxes.YTickLabels={''};
                    hold(app.UIAxes,'off');
                    legend(app.UIAxes,{'-30°','-20°','-10°','0°','10°','20°','30°','40°','50°','60°','70°','80°'});
                    xlabel(app.UIAxes,"Deg/Az");
                    ylabel(app.UIAxes,"us");
                case 4
                    to_plot=find(app.pos_sph(:,1)==0);
                    h=squeeze(app.hrirs(to_plot,:,1)); h=[h,zeros(size(h,1),512-64)];
                    h_f=abs(fft(h'))'; h_f=20*log(h_f); h_f=h_f(:,1:257);
                    surf(app.UIAxes,app.freq,app.pos_sph(to_plot,2),h_f,'EdgeColor','interp','FaceColor','interp');
                    app.UIAxes.XTick=[100 1000 4000 8000 16000];
                    app.UIAxes.XTickLabels={'100','1k','4k','8k','16k'};
                    app.UIAxes.XScale="log";
                    app.UIAxes.YLim=[-30 90];
                    view(app.UIAxes,2);
                    app.UIAxes.XGrid='on';
                    app.UIAxes.YGrid='on';
                    app.UIAxes.XLim=[100 16000];
                    xlabel(app.UIAxes,"Hz");
                    ylabel(app.UIAxes,"Deg/el");
                    cb=colorbar(app.UIAxes);
                    caxis(app.UIAxes,[-90 10]);
                    % cb.Limits([-90 10]);
                case 5
                    to_plot=find(app.pos_sph(:,2)==0);
                    h=squeeze(app.hrirs(to_plot,:,1)); h=[h,zeros(size(h,1),512-64)];
                    h_f=abs(fft(h'))'; h_f=20*log(h_f); h_f=h_f(:,1:257);
                    surf(app.UIAxes,app.freq,app.pos_sph(to_plot,1),h_f,'EdgeColor','interp','FaceColor','interp');
                    app.UIAxes.YLim=[0 350];                    
                    app.UIAxes.XTick=[100 1000 4000 8000 16000];
                    app.UIAxes.XTickLabels={'100','1k','4k','8k','16k'};
                    app.UIAxes.XScale="log";                    
                    view(app.UIAxes,2);
                    app.UIAxes.XGrid='on';
                    app.UIAxes.YGrid='on';
                    xlabel(app.UIAxes,"Hz");
                    ylabel(app.UIAxes,"Deg/az");
                    app.UIAxes.XLim=[100 16000];
                    cb=colorbar(app.UIAxes);
                    % cb.Limits([-90 10]);
                    caxis(app.UIAxes,[-90 10]);
            end
            % set(app.UIAxes,'FontSize',18);
            app.UIAxes.FontSize=18;
        end

        function audio_frame=Next_block(app)            
            audio_frame=app.audio_samples(app.audio_idx:app.audio_idx+(app.buffer_size-1));
            app.audio_idx=app.audio_idx+app.buffer_size;
            if app.audio_idx>=app.audio_size
                app.audio_ended=true;
                app.audio_idx=app.audio_idx-app.audio_size;
            end
        end

        function Player(app)       
            % disp(size(app.itds));
            app.audio_ended=false; app.audio_idx=1;
            app.is_playing=true;
            app.PlayStop.Text='Stop (P)';
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','ZoomAudioDevice');
            deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','MacBook Pro Speakers');
            % deviceWriter = audioDeviceWriter('SampleRate',35200,'Device','External Headphones');
            buffer=zeros(4096,2);
            buffer_in=zeros(128,1);
            han=hann(256,'periodic');            
            while app.is_playing
                    audioIn = app.Next_block();

                    buffer_in=[buffer_in;audioIn];
                    for j=[(0:128:app.buffer_size-1)+1]
                        tmp=fft([buffer_in(j:j+255).*han;zeros(256,1)]);
                        l_d=0; r_d=0;
                        if app.itds(app.id_pos)<0, l_d=round(256*abs(app.itds(app.id_pos))); else, r_d=round(256*abs(app.itds(app.id_pos))); end
                        % buffer(j:j+511,1)=buffer(j:j+511,1)+real(ifft(tmp)); buffer(j:j+511,2)=buffer(j:j+511,2)+real(ifft(tmp));
                        buffer(j+l_d:l_d+j+511,1)=buffer(j+l_d:l_d+j+511,1)+real(ifft(app.filters(:,1).*tmp)); 
                        buffer(j+r_d:r_d+j+511,2)=buffer(j+r_d:r_d+j+511,2)+real(ifft(app.filters(:,2).*tmp));
                        % buffer(j:j+511,:)=buffer(j:j+511,:)+real(ifft([app.filter(:,1).*tmp,app.filter(:,2).*tmp]));
                    end                
                    deviceWriter(fliplr(buffer(1:app.buffer_size,:)));
                    % pause(0);
                    drawnow limitrate
                    buffer=[buffer(app.buffer_size+1:end,:);zeros(app.buffer_size,2)];
                    buffer_in=buffer_in(app.buffer_size+1:end);
                    if app.audio_ended && ~strcmp(app.Loop.Value,'Loop'), break; end                    
            end
            app.PlayStop.Text='Play (P)';
            app.is_playing=false;
            release(deviceWriter);
            
        end

    end
    methods (Access = public,Static)        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.parapool=gcp;
            app.decoder = importNetworkFromTensorFlow('./Models/decoder');
            app.itd_decoder= importNetworkFromTensorFlow('./Models/itd_decoder');
            load('pca_info.mat'); app.pca_info=pca_info;
            load('pos.mat');
            app.buffer_size=2048;
            [app.audio_samples,Fs]=audioread('./Stimulus/Guitar.wav');                    
            app.audio_size=size(app.audio_samples,1);
            app.audio_samples=[app.audio_samples;app.audio_samples(1:app.buffer_size)];
            app.pos_sph=pos;
            app.pos=sphToRect([pos,repmat(1,[397 1])]);
            app.az=0;app.el=0;
            app.freq= (0 : 512/2) * 35200 / 512;
            app.is_playing=false;            
            app.sliders_v=zeros(1,5);
            app.Update_data();
            app.fixed_positions=[0,-30;0,0;0,30;0,60;0,90;45,0;90,0;180,-30;180,0;180,30];

            app.memory_slots=zeros(9,5);
            app.ranged=false;
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(1)=changingValue;
            % app.Sl1.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(2)=changingValue;
            % app.Sl2.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(3)=changingValue;
            % app.Sl3.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(4)=changingValue;
            % app.Sl4.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            % changingValue = event.Value;
            % app.sliders_v(5)=changingValue;
            % app.Sl5.Text=num2str(round(changingValue,2));
            % app.Update_data();
        end

        % Value changed function: TypePlot
        function TypePlotValueChanged(app, event)
            % value = app.TypePlot.Value;
            app.Update_plots();
        end

        % Value changing function: AzKnob
        function AzKnobValueChanging(app, event)
            value = event.Value;
            if value<0
                app.az=360+value;
            else
                app.az=value;
            end            
            app.AzLabel.Text=[int2str(value) '°'];
            app.Update_filter();
        end

        % Value changing function: ElKnob
        function ElKnobValueChanging(app, event)
            value = event.Value;
            app.el=value;
            app.ElLabel.Text=[int2str(value) '°'];
            app.Update_filter();
        end

        % Button pushed function: PlayStop
        function PlayStopButtonPushed(app, event)
            if ~app.is_playing
                % app.para=parfeval(backgroundPool,@player);                
                % app.para=parfeval(app.parapool,@app.Player,0);
                app.Player;
            else
                app.is_playing=false;
            end
        end

        % Value changed function: AudioFile
        function AudioFileValueChanged(app, event)
            value = app.AudioFile.ValueIndex;
            app.is_playing=false;
            switch value
                case 1
                    [app.audio_samples,Fs]=audioread('./Stimulus/Guitar.wav'); 
                case 2
                    [app.audio_samples,Fs]=audioread('./Stimulus/hvd_002.wav');
                    app.audio_samples=[app.audio_samples;zeros(704,1)];
                case 3
                    [app.audio_samples,Fs]=audioread('./Stimulus/TrainNoise.wav');
                    app.audio_samples=[app.audio_samples;zeros(704,1)];
                case 4
                    [filename,pathname] = uigetfile('*.wav');
                    pathname=[pathname filename];
                    [app.audio_samples,Fs]=audioread(pathname); app.audio_samples=app.audio_samples(:,1);
                    if Fs~=35200, app.audio_samples=resample(app.audio_samples,35200,Fs); end
            end
            app.audio_size=size(app.audio_samples,1);
            app.audio_samples=[app.audio_samples;app.audio_samples(1:app.buffer_size)];
        end

        % Button pushed function: Random
        function RandomButtonPushed(app, event)
            tmp=randi([1 397]);
            app.el=app.pos_sph(tmp,2);
            app.az=app.pos_sph(tmp,1);
            app.ElKnob.Value=app.el;
            app.ElLabel.Text=[int2str(app.el) '°'];
            if app.az>180
                app.AzKnob.Value=360-app.az;
            else
                app.AzKnob.Value=app.az;
            end
            app.AzLabel.Text=[int2str(app.AzKnob.Value) '°'];
            app.Update_filter();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if isstrprop(key, 'digit')
                [~,value]=ismember(key,['1','2','3','4','5','6','7','8','9','0']);
                app.el=app.fixed_positions(value,2);
                app.az=app.fixed_positions(value,1);
                app.ElKnob.Value=app.el;
                app.ElLabel.Text=[int2str(app.el) '°'];
                if app.az>180
                    app.AzKnob.Value=360-app.az;
                else
                    app.AzKnob.Value=app.az;
                end
                app.AzLabel.Text=[int2str(app.AzKnob.Value) '°'];
                app.Update_filter();
            else
                switch key
                    case 'p'
                        if ~app.is_playing
                            app.Player();
                        else
                            app.is_playing=false;  
                        end
                    case 'r'
                        tmp=randi([1 397]);
                        app.el=app.pos_sph(tmp,2);
                        app.az=app.pos_sph(tmp,1);
                        app.ElKnob.Value=app.el;
                        app.ElLabel.Text=[int2str(app.el) '°'];
                        if app.az>180
                            app.AzKnob.Value=360-app.az;
                        else
                            app.AzKnob.Value=app.az;
                        end
                        app.AzLabel.Text=[int2str(app.AzKnob.Value) '°'];
                        app.Update_filter();
                end
            end            
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            value = app.Slider1.Value;
            app.sliders_v(1)=value;
            app.Sl1.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.sliders_v(2)=value;
            app.Sl2.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            app.sliders_v(3)=value;
            app.Sl3.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
            value = app.Slider4.Value;
            app.sliders_v(4)=value;
            app.Sl4.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            value = app.Slider5.Value;
            app.sliders_v(5)=value;
            app.Sl5.Text=num2str(round(value,2));
            app.Update_data();
        end

        % Value changed function: FPositions
        function FPositionsValueChanged(app, event)
            value = app.FPositions.ValueIndex;
            app.el=app.fixed_positions(value,2);
            app.az=app.fixed_positions(value,1);
            app.ElKnob.Value=app.el;
            app.ElLabel.Text=[int2str(app.el) '°'];
            if app.az>180
                app.AzKnob.Value=360-app.az;
            else
                app.AzKnob.Value=app.az;
            end
            app.AzLabel.Text=[int2str(app.AzKnob.Value) '°'];
            app.Update_filter();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '1';
            app.Slider1 = uislider(app.GridLayout2);
            app.Slider1.Limits = [-1 1];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '2';
            app.Slider2 = uislider(app.GridLayout2);
            app.Slider2.Limits = [-1 1];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider_2Label = uilabel(app.GridLayout2);
            app.Slider_2Label.Text = '3';
            app.Slider3 = uislider(app.GridLayout2);
            app.Slider3.Limits = [-1 1];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.Slider_3Label = uilabel(app.GridLayout2);
            app.Slider_3Label.Text = '4';
            app.Slider4 = uislider(app.GridLayout2);
            app.Slider4.Limits = [-1 1];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.Slider_4Label = uilabel(app.GridLayout2);
            app.Slider_4Label.Text = '5';
            app.Slider5 = uislider(app.GridLayout2);
            app.Slider5.Limits = [-1 1];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.ParametersLabel = uilabel(app.GridLayout2);
            app.ParametersLabel.Text = 'Parameters';
            app.Sl1 = uilabel(app.GridLayout2);
            app.Sl1.Text = '0';
            app.Sl2 = uilabel(app.GridLayout2);
            app.Sl2.Text = '0';
            app.Sl3 = uilabel(app.GridLayout2);
            app.Sl3.Text = '0';
            app.Sl4 = uilabel(app.GridLayout2);
            app.Sl4.Text = '0';
            app.Sl5 = uilabel(app.GridLayout2);
            app.Sl5.Text = '0';
            app.MListBoxLabel = uilabel(app.GridLayout2);
            app.MListBoxLabel.Text = 'M';
            app.MemListBox = uilistbox(app.GridLayout2);
            app.MemListBox.Items = {'memory 1', 'memory 2', 'memory 3', 'memory 4', 'memory 5', 'memory 6', 'memory 7', 'memory 8', 'memory 9'};
            app.MemListBox.Value = 'memory 1';
            app.SSliderLabel = uilabel(app.GridLayout2);
            app.SSliderLabel.Text = 'S';
            app.MemSlider = uislider(app.GridLayout2, 'range');
            app.MemSlider.Limits = [1 9];
            app.MemSlider.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.MemSlider.MinorTicks = [1 2 3 4 5 6 7 8 9];
            app.MemSlider.Value = [1 3];
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.Text = 'Save';
            app.UserangeButton = uibutton(app.GridLayout2, 'push');
            app.UserangeButton.Text = {'Use '; 'range'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TypeofplotDropDownLabel = uilabel(app.GridLayout4);
            app.TypeofplotDropDownLabel.Text = 'Type of plot';
            app.TypePlot = uidropdown(app.GridLayout4);
            app.TypePlot.Items = {'HRIRs', 'HRTFs', 'ITDs', 'Spectogram (El)', 'Spectogram (Az)'};
            app.TypePlot.ValueChangedFcn = createCallbackFcn(app, @TypePlotValueChanged, true);
            app.TypePlot.Value = 'HRIRs';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.AzimuthAzKnobLabel = uilabel(app.GridLayout6);
            app.AzimuthAzKnobLabel.Text = 'Azimuth (Az)';
            app.AzKnob = uiknob(app.GridLayout6, 'continuous');
            app.AzKnob.Limits = [-180 180];
            app.AzKnob.ValueChangingFcn = createCallbackFcn(app, @AzKnobValueChanging, true);
            app.ElevationElKnobLabel = uilabel(app.GridLayout6);
            app.ElevationElKnobLabel.Text = 'Elevation (El)';
            app.ElKnob = uiknob(app.GridLayout6, 'continuous');
            app.ElKnob.Limits = [-30 90];
            app.ElKnob.ValueChangingFcn = createCallbackFcn(app, @ElKnobValueChanging, true);
            app.AzLabel = uilabel(app.GridLayout6);
            app.AzLabel.Text = '0º';
            app.ElLabel = uilabel(app.GridLayout6);
            app.ElLabel.Text = '0º';
            app.PlayStop = uibutton(app.GridLayout6, 'push');
            app.PlayStop.ButtonPushedFcn = createCallbackFcn(app, @PlayStopButtonPushed, true);
            app.PlayStop.Text = 'Play (P)';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.AudioLabel = uilabel(app.GridLayout8);
            app.AudioLabel.Text = 'Audio (P):';
            app.Loop = uiswitch(app.GridLayout8, 'slider');
            app.Loop.Items = {'No loop', 'Loop'};
            app.Loop.Value = 'No loop';
            app.FixedpositionsDropDownLabel = uilabel(app.GridLayout6);
            app.FixedpositionsDropDownLabel.Text = 'Fixed positions';
            app.FPositions = uidropdown(app.GridLayout6);
            app.FPositions.Items = {'[0,-30]', '[0,0]', '[0,30]', '[0,60]', '[0,90]', '[45,0]', '[90,0]', '[180,-30]', '[180,0]', '[180,30]'};
            app.FPositions.ValueChangedFcn = createCallbackFcn(app, @FPositionsValueChanged, true);
            app.FPositions.Value = '[0,-30]';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.AudiofileDropDownLabel = uilabel(app.GridLayout7);
            app.AudiofileDropDownLabel.Text = 'Audio file';
            app.AudioFile = uidropdown(app.GridLayout7);
            app.AudioFile.Items = {'Guitar', 'Voice', 'Noise', 'Custom'};
            app.AudioFile.ValueChangedFcn = createCallbackFcn(app, @AudioFileValueChanged, true);
            app.AudioFile.Value = 'Guitar';
            app.Random = uibutton(app.GridLayout7, 'push');
            app.Random.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.Random.Text = {'Random '; 'position (R)'};
            app.Export = uibutton(app.GridLayout7, 'push');
            app.Export.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_perso

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end